classdef tracKit < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Tool                      matlab.ui.Figure
        LoadVideo                 matlab.ui.control.Button
        MarkArea                  matlab.ui.control.Button
        StartsLabel               matlab.ui.control.Label
        Start                     matlab.ui.control.Spinner
        EndsSpinnerLabel          matlab.ui.control.Label
        End                       matlab.ui.control.Spinner
        TimeEditFieldLabel        matlab.ui.control.Label
        Time                      matlab.ui.control.NumericEditField
        Analyse                   matlab.ui.control.Button
        BackgroundFill            matlab.ui.control.Button
        BodyPartsButtonGroup      matlab.ui.container.ButtonGroup
        BodyButton                matlab.ui.control.RadioButton
        NoseBodyButton            matlab.ui.control.RadioButton
        LabelledVideoSwitchLabel  matlab.ui.control.Label
        LabelledVideoSwitch       matlab.ui.control.Switch
        BinaryThresholdLabel      matlab.ui.control.Label
        BT                        matlab.ui.control.Spinner
        EnterInfoEditField        matlab.ui.control.EditField
        CreateBackground          matlab.ui.control.Button
    end

    
    properties (Access = public)
        v % Video
        changingValue1 % Video analysis start time
        changingValue2 % Video analysis end time
        show1 % Video analysis first frame
        show2 % Video analysis end frame
        path3 % Video Path
        filename
        filename_3
        Background
        cr
        averagemajoraxis
        crop
        ClearOutsideMask
        animalcentroids
        animalnose
        hImage
        T
        video
    end
    

    methods (Access = private)

        % Button pushed function: LoadVideo
        function LoadVideoPushed(app, event)
            % Close all the figures first
            figure
            close all
            % Imports selected video
            [filename_2, pathname_2] = uigetfile('*.mov;*.wmv;*.mp4;*.avi','Open Video File');
            if filename_2 == 0
                % If user clicked the Cancel button.
                return;
            end
            app.LoadVideo.BackgroundColor='y';
            app.filename_3 = cellstr(filename_2);
            pathname_3 = cellstr(pathname_2);
            app.video = fullfile(pathname_3{1},app.filename_3{1});
            % Get the name video and create folder with video name
            [folder, baseFileNameNoExt, ~] = fileparts(app.video);
            fileName = fullfile(pathname_3{1});
            dotLocations = find(fileName == '.');
            if isempty(dotLocations)
                nameBeforeFirstDot = fileName;
            else
                nameBeforeFirstDot = fileName(1:dotLocations(1)-1);
            end
            cd(folder)
%             if ~exist([folder,'\',baseFileNameNoExt],'dir')
%                 mkdir (baseFileNameNoExt)
%             end
%             cd(baseFileNameNoExt)
            app.path3=nameBeforeFirstDot;
            app.filename=baseFileNameNoExt;
            % creates video reader and shows firstframe from the video
            app.v = VideoReader(app.video);
            frame = read(app.v, 1);
            app.hImage=imshow(frame);
            %imwrite(app.Background,'Background.tif')
            app.Start.Value=1;
            app.End.Value=floor(app.v.Duration)-1;
            app.LoadVideo.BackgroundColor='cyan';
        end

        % Button pushed function: MarkArea
        function MarkAreaPushed(app, event)
            numrois=2;
            map=hsv(numrois);
            custommap = map;
            imshow(app.show1)
            set(gcf, 'Position', get(0,'Screensize'));
            xlabel('Masks outside region of interest')
            roi = drawpolygon('FaceAlpha',0,'Color',custommap(1,:));
            app.ClearOutsideMask=createMask(roi);
            croproi=roi.Position;
            app.MarkArea.Text='Determining Body Parts';
            close
            app.crop=[min(croproi(:,1)),min(croproi(:,2)),max(croproi(:,1))-min(croproi(:,1)),max(croproi(:,2))-min(croproi(:,2))];
            wanted_frames=floor(5*app.v.FrameRate);
            a = app.changingValue1*app.v.FrameRate;
            b = app.changingValue2*app.v.FrameRate;
            x= floor((b-a).*rand(wanted_frames,1) + a);
            indices = sort(x);
            BackgroundCropped=imcrop(app.Background,app.crop);
            ClearOutsideMaskCropped=imcrop(app.ClearOutsideMask,app.crop);
            A=size(BackgroundCropped);
            sample = struct('cdata',zeros(A(1),A(2),1,'uint8'),...
                'colormap',[]);
            app.averagemajoraxis=[];
            app.T=[];
            if app.BodyButton.Value==1
                oc=1;
            else
                oc=0;
            end
            for i=1:length(indices)
                k=indices(i);
                frame=rgb2gray(read(app.v,k));
                RandomFrame = imcrop(frame,app.crop);
                MaskedImage = imabsdiff(BackgroundCropped,(RandomFrame));
                MaskedImage(~ClearOutsideMaskCropped) = 1;
                threshold=graythresh(MaskedImage);
                app.T=[app.T,threshold];
                MaskedBinary=imbinarize(MaskedImage,threshold);
                FilledMaskedBinary = imfill(MaskedBinary,'holes');
                FilledMaskedBinaryLargest = bwareafilt(FilledMaskedBinary,1);
                C=regionprops(FilledMaskedBinaryLargest, {'Centroid','MajorAxisLength'});
                FilledMaskedBinaryLargestEroded = imerode(FilledMaskedBinaryLargest,strel('diamond',floor(C.MajorAxisLength/18)));
                windowSize =floor(C.MajorAxisLength/10);
                kernel = ones(windowSize) / windowSize ^ 2;
                app.averagemajoraxis=[app.averagemajoraxis;C.MajorAxisLength];
                FilledMaskedBinaryLargestErodedConv = conv2(single(FilledMaskedBinaryLargestEroded), kernel, 'same');
                FilledMaskedBinaryLargestErodedConv = medfilt2(FilledMaskedBinaryLargestErodedConv > 0.5); % Rethreshold
                FilledMaskedBinaryLargestErodedConvLargest = bwareafilt(FilledMaskedBinaryLargestErodedConv,1);
                if(~isempty(C))
                    COM = C.Centroid;
                end
                % If Dilate for seedpoint
                if(FilledMaskedBinaryLargestErodedConvLargest(floor(COM(2)),floor(COM(1)))==0)
                    FilledMaskedBinaryLargestErodedConvLargest = imdilate(FilledMaskedBinaryLargestErodedConvLargest,strel('disk',floor(C.MajorAxisLength/10)));
                end
                % Identify the nose
                D = bwdistgeodesic(FilledMaskedBinaryLargestErodedConvLargest,floor(COM(1)),floor(COM(2)),'quasi-euclidean');
                [~,x] = max(D(:));
                [y,x]=ind2sub(size(D),x);
                xnose = x;
                ynose = y;
                radii=floor(C.MajorAxisLength/10);
                RGB = insertShape(RandomFrame,'FilledCircle',[COM(1) COM(2) radii],'color','y','LineWidth',radii);
                RGB = insertShape(RGB,'FilledCircle',[xnose ynose radii],'color','g','LineWidth',radii);
                sample(i).cdata = RGB;
            end
            app.averagemajoraxis=median(app.averagemajoraxis);
            app.T=mean(app.T);
            imshow(RGB)
            montage({sample(1).cdata,sample(2).cdata,sample(3).cdata,sample(4).cdata,sample(5).cdata,sample(6).cdata})
            app.MarkArea.Text='Mark Area';
            app.MarkArea.BackgroundColor='cyan';
            app.BT.Value=floor(app.T*100);
            %cd([app.path3,'\',app.filename])
        end

        % Value changing function: Start
        function StartValueChanging(app, event)
            app.changingValue1 = event.Value;
            if  app.changingValue1<app.v.Duration
                app.show1 = read(app.v,floor(app.changingValue1*app.v.FrameRate));
                app.hImage=imshow(app.show1);
            end
            % Changes Button Color and Values
            app.MarkArea.Text='Mark Area';
            app.MarkArea.BackgroundColor='White';
            app.Analyse.Text='Analyse';
            app.Analyse.BackgroundColor='White';
            app.Time.Value =0;
            
        end

        % Value changing function: End
        function EndValueChanging(app, event)
            app.changingValue2 = event.Value;
            if  app.changingValue2<app.v.Duration
                app.show2 = read(app.v,floor(app.changingValue2*app.v.FrameRate));
                app.hImage=imshow(app.show2);
            end
            app.Time.Value =app.changingValue2-app.changingValue1;
            % Changes Button Color and Values
            app.MarkArea.Text='Mark Area';
            app.MarkArea.BackgroundColor='White';
            app.Analyse.Text='Analyse';
            app.Analyse.BackgroundColor='White';
        end

        % Button pushed function: Analyse
        function AnalyseButtonPushed(app, event)
            f=uifigure;
            d = uiprogressdlg(f,'Title','Analysing',...
                'Message','','Cancelable','on');
            drawnow
            pause(.5)
            pause(1)
            medfilt=5;
            app.Analyse.Text='Analysing';
            app.Analyse.BackgroundColor='Yellow';
            app.averagemajoraxis=app.averagemajoraxis;
            BackgroundCropped=imcrop(app.Background,app.crop);
            ClearOutsideMaskCropped=imcrop(app.ClearOutsideMask,app.crop);
            app.animalnose=[];
            app.animalcentroids=[];
            clear cr
            A=size(BackgroundCropped);
            app.cr = struct('cdata',zeros(A(1),A(2),1,'uint8'),...
                'colormap',[]);
            erode=floor(app.averagemajoraxis/18);
            windowSize =floor(app.averagemajoraxis/15);
            kernel = ones(windowSize) / windowSize ^ 2;
            StartFrame=floor(app.changingValue1*app.v.FrameRate)+1;
            EndFrame=floor(app.changingValue2*app.v.FrameRate);
            Full=EndFrame-StartFrame+2;
            if app.BodyButton.Value==1
                oc=1;
            else
                oc=0;
            end
                d.Message = 'Processing';
                i=0;
            for k=StartFrame:1:EndFrame+1
                i=i+1;
                d.Value = i/Full;
                %d.Message = sprintf('%12.0f',((i/Full)*100));
                if d.CancelRequested
                    break
                end
                frame=rgb2gray(read(app.v,k));
                RandomFrame = imcrop(frame,app.crop);
                MaskedImage = medfilt2(imabsdiff(BackgroundCropped,(RandomFrame)),[medfilt,medfilt]);
                MaskedImage(~ClearOutsideMaskCropped) = 1;
                MaskedBinary=imbinarize(MaskedImage,(app.T/100));
                
                %threshold=graythresh(MaskedImage);
                %MaskedBinary=imbinarize(MaskedImage,threshold);
                FilledMaskedBinary = imfill(MaskedBinary,'holes');
                FilledMaskedBinaryLargest = bwareafilt(FilledMaskedBinary,1);
                C=regionprops(FilledMaskedBinaryLargest,{'Centroid','MajorAxisLength',...
                    'Area','Solidity'});
                app.animalcentroids=[app.animalcentroids;cat(1,floor(C.Centroid))];
                if oc==0
                    FilledMaskedBinaryLargestEroded = imerode(FilledMaskedBinaryLargest,...
                        strel('diamond',erode));
                    FilledMaskedBinaryLargestErodedConv = conv2(single(FilledMaskedBinaryLargestEroded), kernel, 'same');
                    FilledMaskedBinaryLargestErodedConv = medfilt2(FilledMaskedBinaryLargestErodedConv > 0.5); % Rethreshold
                    FilledMaskedBinaryLargestErodedConvLargest = bwareafilt(FilledMaskedBinaryLargestErodedConv,1);
                    if(~isempty(C))
                        COM = C.Centroid;
                    end
                    % If Dilate for seedpoint
                    if(FilledMaskedBinaryLargestErodedConvLargest(floor(COM(2)),floor(COM(1)))==0)
                        FilledMaskedBinaryLargestErodedConvLargest = imdilate(FilledMaskedBinaryLargestErodedConvLargest,...
                            strel('disk',floor(app.averagemajoraxis/10)));
                    end
                    % Identify the nose
                    D = bwdistgeodesic(FilledMaskedBinaryLargestErodedConvLargest,floor(COM(1)),floor(COM(2)),'quasi-euclidean');
                    [~,x] = max(D(:));
                    [y,x]=ind2sub(size(D),x);
                    xnose = x;
                    ynose = y;
                    app.animalnose=[app.animalnose;[xnose,ynose]];
                end
            end
            % filter coordinates
            filt=5;
            filteredcom=movmedian(app.animalcentroids,filt);
            filterednose=movmedian(app.animalnose,filt);
            save([[app.EnterInfoEditField.Value],' ',[app.filename]])
            % write labelled video
            if app.LabelledVideoSwitch.Value=="Yes"
                app.Analyse.Text='Creating Video';
                d.Message = 'Creating Labelled Video';
                labelledvideo = ['Labelled ',[app.EnterInfoEditField.Value],' ',[app.filename],' ','.avi'];
                writerObj = VideoWriter(labelledvideo);
                radii=floor(app.averagemajoraxis/20);
                start=floor(app.changingValue1*app.v.FrameRate);
                writerObj.FrameRate = floor(app.v.FrameRate);
                open(writerObj);
                i=0;
                for k=start:1:length(filteredcom)+start-1
                    i=i+1;
                    d.Value = i/Full;
                    %d.Message = sprintf('%12.0f',((i/Full)*100));
                    if d.CancelRequested
                        break
                    end
                    frame=read(app.v,k);
                    I=imcrop(frame,app.crop);
                    RGB = insertShape(I,'FilledCircle',[filteredcom(i,:) radii],'color','y','LineWidth',1);
                    RGB = insertText(RGB,[0 0],num2str(i));
                    if oc==0
                        RGB = insertShape(RGB,'FilledCircle',[filterednose(i,:) radii],'color','g','LineWidth',1);
                    end
                    writeVideo(writerObj,RGB);
                end
                close(writerObj);
            end
            close(d);
            app.Analyse.Text='Analyse';
            app.Analyse.BackgroundColor='cyan';
            close all
            close(f);
        end

        % Button pushed function: BackgroundFill
        function BackgroundFillPushed(app, event)
            numrois=2;
            map=hsv(numrois);
            custommap = map;
            figure;
            imshow(app.Background);
            set(gcf, 'Position', get(0,'Screensize'));
            xlabel('Fill')
            RegionFill = drawpolygon('FaceAlpha',0,'Color',custommap(1,:));
            RegionFillMask = createMask(RegionFill);
            app.Background= regionfill(app.Background,RegionFillMask);
            delete(RegionFill);
            close
            app.hImage=imshow(app.Background);
            
        end

        % Value changing function: BT
        function BTValueChanging(app, event)
            changingValue3 = event.Value;
            medfilt=5;
            BackgroundCropped=imcrop(app.Background,app.crop);
            ClearOutsideMaskCropped=imcrop(app.ClearOutsideMask,app.crop);
            app.T=changingValue3;
            Ic=imcrop(app.show1,app.crop);
            [~,~,o]=size(Ic);
            % if rgb then convert to grayscale
            if o==3
                gray=rgb2gray(Ic);
            else
                gray=Ic;
            end            
                MaskedImage = medfilt2(imabsdiff(BackgroundCropped,(gray)),[medfilt,medfilt]);
                MaskedImage(~ClearOutsideMaskCropped) = 1;
                MaskedBinary=imbinarize(MaskedImage,(app.T/100));
            B = imoverlay(Ic,MaskedBinary,'r');
            imshowpair(MaskedBinary,B,'montage')
        end

        % Button pushed function: CreateBackground
        function CreateBackgroundButtonPushed(app, event)
            app.CreateBackground.BackgroundColor='white';
            app.CreateBackground.Text='Estimating Background';
            app.v = VideoReader(app.video);
            fps=app.v.FrameRate;
            wanted_frames=floor(5*fps);
            a = app.changingValue1*app.v.FrameRate;
            b = app.changingValue2*app.v.FrameRate;
            x= floor((b-a).*rand(wanted_frames,1) + a);
            indices = sort(x);
            frame = read(app.v, wanted_frames(1));
            A=size(frame);
            app.cr= struct('cdata',zeros(A(1),A(2),1,'uint8'),...
                'colormap',[]);
            [m,n,o]=size(frame);
            Abkg = zeros(m,n,1,'uint8');
            % if rgb then convert to grayscale
            if o==3
                for k=1:length(x)
                    frame=read(app.v,indices(k));
                    frame =rgb2gray(frame);
                    Abkg(:,:,k) =uint8(app.cr(1).cdata);
                    app.cr(1).cdata = frame;
                end
            else
                for k=1:length(x)
                    frame=read(app.v,indices(k));
                    Abkg(:,:,k) =uint8(app.cr(1).cdata);
                    app.cr(1).cdata = frame;
                end
            end
            app.Background = uint8(median(double(Abkg),3));
            imshow(app.Background);
            app.hImage=imshow(app.Background);
            app.CreateBackground.Text='Create Background';
            app.CreateBackground.BackgroundColor='cyan';
            %imwrite(app.Background,'Background.tif')
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Tool = uifigure;
            app.Tool.Name = 'tracKit';
            app.LoadVideo = uibutton(app.Tool, 'push');
            app.LoadVideo.ButtonPushedFcn = createCallbackFcn(app, @LoadVideoPushed, true);
            app.LoadVideo.Text = 'Load Video';
            app.MarkArea = uibutton(app.Tool, 'push');
            app.MarkArea.ButtonPushedFcn = createCallbackFcn(app, @MarkAreaPushed, true);
            app.MarkArea.Tooltip = {''};
            app.MarkArea.Text = 'Mark ROI';
            app.StartsLabel = uilabel(app.Tool);
            app.StartsLabel.Text = 'Start (s)';
            app.Start = uispinner(app.Tool);
            app.Start.ValueChangingFcn = createCallbackFcn(app, @StartValueChanging, true);
            app.Start.Limits = [1 Inf];
            app.Start.Value = 1;
            app.EndsSpinnerLabel = uilabel(app.Tool);
            app.EndsSpinnerLabel.Text = 'End (s)';
            app.End = uispinner(app.Tool);
            app.End.ValueChangingFcn = createCallbackFcn(app, @EndValueChanging, true);
            app.End.Limits = [2 Inf];
            app.End.Value = 2;
            app.TimeEditFieldLabel = uilabel(app.Tool);
            app.TimeEditFieldLabel.Text = 'Time';
            app.Time = uieditfield(app.Tool, 'numeric');
            app.Time.Editable = 'off';
            app.Analyse = uibutton(app.Tool, 'push');
            app.Analyse.ButtonPushedFcn = createCallbackFcn(app, @AnalyseButtonPushed, true);
            app.Analyse.Text = 'Analyse';
            app.BackgroundFill = uibutton(app.Tool, 'push');
            app.BackgroundFill.ButtonPushedFcn = createCallbackFcn(app, @BackgroundFillPushed, true);
            app.BackgroundFill.Tooltip = {'Select Only Area Containing the animal'};
            app.BackgroundFill.Text = 'Fix Background';
            app.BodyPartsButtonGroup = uibuttongroup(app.Tool);
            app.BodyPartsButtonGroup.Title = 'Body Parts';
            app.BodyButton = uiradiobutton(app.BodyPartsButtonGroup);
            app.BodyButton.Text = 'Body';
            app.BodyButton.Value = true;
            app.NoseBodyButton = uiradiobutton(app.BodyPartsButtonGroup);
            app.NoseBodyButton.Text = 'Nose+Body';
            app.LabelledVideoSwitchLabel = uilabel(app.Tool);
            app.LabelledVideoSwitchLabel.Text = 'Labelled Video';
            app.LabelledVideoSwitch = uiswitch(app.Tool, 'slider');
            app.LabelledVideoSwitch.Items = {'No', 'Yes'};
            app.LabelledVideoSwitch.Value = 'No';
            app.BinaryThresholdLabel = uilabel(app.Tool);
            app.BinaryThresholdLabel.Text = 'Binary Threshold';
            app.BT = uispinner(app.Tool);
            app.BT.ValueChangingFcn = createCallbackFcn(app, @BTValueChanging, true);
            app.BT.Limits = [0 100];
            app.EnterInfoEditField = uieditfield(app.Tool, 'text');
            app.EnterInfoEditField.Value = ' Trackit';
            app.CreateBackground = uibutton(app.Tool, 'push');
            app.CreateBackground.ButtonPushedFcn = createCallbackFcn(app, @CreateBackgroundButtonPushed, true);
            app.CreateBackground.Tooltip = {'Select Only Area Containing the animal'};
            app.CreateBackground.Text = 'Create Background';
        end
    end

    methods (Access = public)

        % Construct app
        function app = tracKit

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Tool)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Tool)
        end
    end
end